classdef realApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        StatesTab                   matlab.ui.container.Tab
        GridLayout5                 matlab.ui.container.GridLayout
        WorkingFluidLabel           matlab.ui.control.Label
        WorkingFluidDropdown        matlab.ui.control.DropDown
        StateLablel                 matlab.ui.control.EditField
        StateLabelEditField_2Label  matlab.ui.control.Label
        RemoveButton                matlab.ui.control.Button
        PropertyNo1Label            matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        ProcessCheckBox             matlab.ui.control.CheckBox
        ProcessDropDown             matlab.ui.control.DropDown
        UnitLabel_2                 matlab.ui.control.Label
        AddButton                   matlab.ui.control.Button
        TableAStatesListLabel       matlab.ui.control.Label
        ProUnitProp2DropDown        matlab.ui.control.DropDown
        ProUnitProp1DropDown        matlab.ui.control.DropDown
        ProStateUITable             matlab.ui.control.Table
        ProProp2EditField           matlab.ui.control.NumericEditField
        ProProp1EditField           matlab.ui.control.NumericEditField
        ProPropertyNo2DropDown      matlab.ui.control.DropDown
        ProPropertyNo1DropDown      matlab.ui.control.DropDown
        ProcessTab                  matlab.ui.container.Tab
        GridLayout2_2               matlab.ui.container.GridLayout
        AllProcessesCheckBox        matlab.ui.control.CheckBox
        ProcessListBoxLabel         matlab.ui.control.Label
        ProcessListBox              matlab.ui.control.ListBox
        ProcessLabelEditField       matlab.ui.control.EditField
        ProcessLabelEditFieldLabel  matlab.ui.control.Label
        ProRemoveButton             matlab.ui.control.Button
        FinalStateDropDown          matlab.ui.control.DropDown
        FinalStateDropDownLabel     matlab.ui.control.Label
        StartingStateDropDown       matlab.ui.control.DropDown
        StartingStateDropDownLabel  matlab.ui.control.Label
        ProcessAddButton            matlab.ui.control.Button
        ProcessDropDownNew          matlab.ui.control.DropDown
        ProcessDropDown_2Label      matlab.ui.control.Label
        ProUITable                  matlab.ui.control.Table
        PlotsTab                    matlab.ui.container.Tab
        GridLayout3                 matlab.ui.container.GridLayout
        PlotFluidDropDown           matlab.ui.control.DropDown
        PlotFluidLabel              matlab.ui.control.Label
        UnitIsentropicDropDown      matlab.ui.control.DropDown
        UnitIsoVolDropDown          matlab.ui.control.DropDown
        UnitIsoThermDropDown        matlab.ui.control.DropDown
        UnitIsoBarDropDown          matlab.ui.control.DropDown
        UnitPlotYDropDownLabel      matlab.ui.control.Label
        UnitPlotXDropDownLabel      matlab.ui.control.Label
        UnitPlotYDropDown           matlab.ui.control.DropDown
        UnitPlotXDropDown           matlab.ui.control.DropDown
        IsentropicValue             matlab.ui.control.EditField
        IsentropicCheckBox          matlab.ui.control.CheckBox
        ConstantVolValue            matlab.ui.control.EditField
        IsoThermValue               matlab.ui.control.EditField
        ConstantVolCheckBox         matlab.ui.control.CheckBox
        IsoThermCheckBox            matlab.ui.control.CheckBox
        IsoBarCheckBox              matlab.ui.control.CheckBox
        IsoBarLineValue             matlab.ui.control.EditField
        IsoLinesCheckBox            matlab.ui.control.CheckBox
        PlotButton                  matlab.ui.control.Button
        PlotDropDown                matlab.ui.control.DropDown
        UIAxes                      matlab.ui.control.UIAxes
        DPlotTab                    matlab.ui.container.Tab
        GridLayout4                 matlab.ui.container.GridLayout
        Plot3D                      matlab.ui.control.UIAxes
        PropertyCalcoldTab          matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        UnitLabel                   matlab.ui.control.Label
        CalculateButton             matlab.ui.control.Button
        StatesPropertiesLabel       matlab.ui.control.Label
        UnitProp2DropDown           matlab.ui.control.DropDown
        UnitProp1DropDown           matlab.ui.control.DropDown
        UITable                     matlab.ui.control.Table
        Prop2EditField              matlab.ui.control.NumericEditField
        Prop1EditField              matlab.ui.control.NumericEditField
        PropertyNo2DropDown         matlab.ui.control.DropDown
        PropertyNo2DropDownLabel    matlab.ui.control.Label
        PropertyNo1DropDown         matlab.ui.control.DropDown
        PropertyNo1DropDownLabel    matlab.ui.control.Label
    end

    properties (Access = private)
        % set false to disable printing to command line, etc.
        debug = true
        %
        %         T
        %         P
        %         v
        %         u
        %         h
        Table
        Critical
        StateCounter = 1;
        ProcessCounter = 1;
        TableData % Description
        StateInd
        ProInd
        DataPVT
        fontSize = 18;
        fontSizeFigure = 18;
        IsoValuesT;
        hf
        hFontSize
        defaultUnits = struct('v','m^3/kg', 'P','bar', 'T',[char(0176),'C'], 'uh','kJ/kg', 's','kJ/kgK');
        %allUnits = struct('v',{'m3_kg','L_kg'}, 'P',{'kPa','bar','Pa','MPa','psi','psf'} ...
        %'T',{'C','K','R','F'}, 'uh',{'kJ_kg','J_kg','Btu_lb'}, 's',{'kJ_kg','J_kg','Btu_lb'});
        allUnits = struct();
        plotTypes = {'P - V', 'T - V', 'T - S'};
        scaleFactor = 1.5

        %the color of the P-V line itself
        plotColors = struct('TV','-b','PV','b','TS','b')

        %the color of isolines
        isoLineColors = struct('Isobaric','green','Isothermal','red','Isovolumetric','magenta','Isentropic','cyan')
        %base lines are blue, processeses are black. Remaining colors are
        %red, green, cyan, magenta, yellow, and white

        %we will differentiate each iso line by different styles and
        %markers
        isoLineStyles = {'-', '--', ':', '-.'}
        isoLineMarkers = {'none','o', '+', '*', '.','x'}

        workingFluids = {'Water','R134a'}; %does need to be single quotes apparently

        %defaultStateLabels = []
        plotLimits = struct();
        plotLimitsWater = struct();
        plotLimitsR134a = struct();

        uiPlot

        proStates
        lineWidth = 2;
        axisWidth = 2;
        isoLineWidth = 1.5;
        processLineWidth = 2;

        %percentage that values must differ to not be recognized as an
        %iso-process
        processDetectTolerance = 0.01;
        saturateDetectPTTolerance = 0.01;

        %THIS NEEDS TO MATCH WITH THE PROCESS DETECT FUNCTION
        Processes = {'Isobaric','Isothermal','Constant Volume','Isentropic','Unknown'};

        %label for when a process has more than one fluid in it
        mixedFluidProcessLabel = 'Mixed';

        legendData = {};
        legendData3D = {};

        inputHistory = {0,0,0,0,0,0,0,0}
        %format
        %inputHistory = {'statename','p1',val1,unit1,'p2',val2,unit2,fluidType}
        %app.inputHistory(currentSize+1,1:7)
    end
    % context menu


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            [app.Table,app.Critical] = GenTableNew();
            %[app.Table,app.Critical] = GenTableR134a();
            app.DataPVT = PVTData();


            app.hf = findobj(app.UIFigure, '-property', 'FontSize');
            %app.hFontSize = cell2mat(get(app.hf,'FontSize'));
            %position = app.UIFigure.Position;

            %widthF = position(3);

            %pressure: ['kPa','bar','Pa,'MPa','psi','psf']
            %temperature: ['^oC','K','^oR','^oF']
            %specific volume: ['m^3/kg','L/kg']
            %energy: [J,kJ]
            %specific energy: ['kJ/kg','J/kg','Btu/lb']
            %specific entropy: ['kJ/kgK','J/kgK','Btu/lbR']


            %set global font size
            newFontSize = 18;%double(widthF) * app.hFontSize / 1463.0;
            set(app.hf,{'FontSize'}, num2cell(newFontSize));
            app.allUnits.v = {'m^3/kg','L/kg'};
            app.allUnits.P = {'bar','kPa','Pa','MPa','psi','psf'};
            app.allUnits.T = {[char(0176),'C'],'K',[char(0176),'R'],[char(0176),'F']};
            app.allUnits.uh = {'kJ/kg','J/kg','Btu/lb'};
            app.allUnits.s = {'kJ/kgK','J/kgK',['Btu/lb',char(0176),'R']};

            %{[char(0176), 'C'],[char(0176), 'F'],'K'};

            app.WorkingFluidDropdown.Items = app.workingFluids;
            app.PlotFluidDropDown.Items = app.workingFluids;

            app.PlotDropDown.Items = app.plotTypes;
            %plotTypes = {'P - V', 'T - V', 'T - S'};

            %initialize these so that the defaults work and aren't empty
            app.UnitPlotXDropDown.Items = app.allUnits.v;
            app.UnitPlotYDropDown.Items = app.allUnits.P;

            %position / size
            posiSize = get(app.UIFigure,'Position');

            newPosition = posiSize;
            % newPosition(3) = posiSize(1) + app.scaleFactor*(posiSize(3) - posiSize(1));
            % newPosition(4) = posiSize(2) + app.scaleFactor*(posiSize(4) - posiSize(2));

            newPosition(3) = app.scaleFactor*posiSize(3);
            newPosition(4) = app.scaleFactor*posiSize(4);

            set(app.UIFigure,'Position',newPosition)

            %cosmetic changes
            %no matter what?
            box(app.UIAxes,'on')
            set(app.UIAxes,'linewidth',app.axisWidth,'FontSize',app.fontSizeFigure)
            grid(app.UIAxes,'on')


            %-2.0694 0.8472 12.6389 9.3056


            % posiSize = get(app.UIFigure,'PaperPosition')
            %
            % newPosition = posiSize;
            % newPosition(3) = posiSize(1) + app.scaleFactor*(posiSize(3) - posiSize(1))
            % newPosition(4) = posiSize(2) + app.scaleFactor*(posiSize(4) - posiSize(2))
            %
            % set(app.UIFigure,'PaperPosition',newPosition)


            %get(app.UIFigure)
            %get(app.UnitProp1DropDown)

            %testing latex interpretation
            %set(app.UnitProp1DropDown,'Interpreter','latex')


            app.UnitIsoThermDropDown.Items = app.allUnits.T;
            app.UnitIsoBarDropDown.Items = app.allUnits.P;
            app.UnitIsoVolDropDown.Items = app.allUnits.v;
            app.UnitIsentropicDropDown.Items = app.allUnits.s;


            %initialize process list
            app.ProcessDropDownNew.Items = app.Processes;

            % set default process dropdown to unknown
            %a = app.ProcessDropDownNew.Items
            app.ProcessDropDownNew.Value = {'Unknown'};

            % set default two properties to pressure and temperature
            app.ProPropertyNo1DropDown.Value = 'Pressure';
            app.ProPropertyNo2DropDown.Value = 'Temperature';

            app.ProUnitProp1DropDown.Items = app.allUnits.P;
            app.ProUnitProp2DropDown.Items = app.allUnits.T;

            app.ProUnitProp1DropDown.Value = app.defaultUnits.P;
            app.ProUnitProp2DropDown.Value = app.defaultUnits.T;


            %plot axis bounds for water and R134a, TV, PV, TS
            % Format: [xmin,xmax,ymin,ymax]
            app.plotLimitsWater.TV = [10^-3, 5*10^-1, 0, 500];
            app.plotLimitsWater.PV = [10^-3, 5*10^-1, 0, 250];
            app.plotLimitsWater.TS = [0, 9, 0, 500];

            app.plotLimitsR134a.TV = [6*10^-4, 1.3*10^-1, -40, 100];
            app.plotLimitsR134a.PV = [6*10^-4, 1.3*10^-1, 0, 40];
            app.plotLimitsR134a.TS = [0, 1, -40, 100];

            app.plotLimits = app.plotLimitsWater;

        end

        % Value changed function: PropertyNo1DropDown
        function PropertyNo1DropDownValueChanged(app, event)
            %
            %             %%%%% DEPRECATED %%%%%%%
            %             % First Property DropDown
            %
            %             fprintf("resetting prop2 dropdown")
            %             app.PropertyNo2DropDown.Items = {'Select','Pressure','Temperature','Specific Volume - v','Internal Energy - u','Enthalpy - h','Entropy - s','quality - x'};
            %             Prop1 = app.PropertyNo1DropDown.Value;
            %             app.UnitProp2DropDown.Items = {};
            %
            %                 switch Prop1
            %                     case 'Select'
            %                         app.UnitProp1DropDown.Items = {};
            %                         %fprintf("1")
            %
            %                     case 'Pressure'
            %                         %app.UnitProp1DropDown.Items = {'bar','kPa','MPa','lbt/in2'};
            %                         app.UnitProp1DropDown.Items = app.allUnits.P;
            %
            %                         app.PropertyNo2DropDown.Items(2) = [];
            %                         %fprintf("2")
            %
            %                     case 'Temperature'
            %                         %app.UnitProp1DropDown.Items = {[char(0176), 'C'],[char(0176), 'F'],'K'};
            %                         app.UnitProp1DropDown.Items = app.allUnits.T;
            %
            %                         app.PropertyNo2DropDown.Items(3) = [];
            %                         %fprintf("3")
            %
            %                     case 'Specific Volume - v'
            %                         %app.UnitProp1DropDown.Items = {'m3/Kg','ft3/lb','MPa','lbt/in2'};
            %                         app.UnitProp1DropDown.Items = app.allUnits.v;
            %
            %                         app.PropertyNo2DropDown.Items(4) = [];
            %                         %fprintf("4")
            %
            %                     case 'Internal Energy - u'
            %                         %app.UnitProp1DropDown.Items = {'KJ/Kg','Btu/lb'};
            %                         app.UnitProp1DropDown.Items = app.allUnits.uh;
            %
            %                         app.PropertyNo2DropDown.Items(5) = [];
            %                         %fprintf("5")
            %
            %                     case 'Enthalpy - h'
            %                         %app.UnitProp1DropDown.Items = {'KJ/Kg','Btu/lb'};
            %                         app.UnitProp1DropDown.Items = app.allUnits.uh;
            %                         app.PropertyNo2DropDown.Items(6) = [];
            %                         %fprintf("6")
            %
            %                     case 'Entropy - s'
            %                         %app.UnitProp1DropDown.Items = {'KJ/Kg * K'};
            %                         app.UnitProp1DropDown.Items = app.allUnits.s;
            %
            %                         app.PropertyNo2DropDown.Items(7) = [];
            %                         app.allUnits.s
            %
            %                     case 'quality - x'
            %                         app.UnitProp1DropDown.Items = {'No Unit'};
            %                         app.PropertyNo2DropDown.Items(8) = [];
            %                         %fprintf("8")
            %                 end
            %
            %

        end

        % Value changed function: PropertyNo2DropDown
        function PropertyNo2DropDownValueChanged(app, event)
            %             %%%%%%%%% DEPRECATED %%%%%%%%%%%%%

            %                   Prop2 = app.PropertyNo2DropDown.Value;
            %
            %                 switch Prop2
            %
            %                     case 'Select'
            %                         app.UnitProp2DropDown.Items = {};
            %
            %                     case 'Pressure'
            %                         %app.UnitProp2DropDown.Items = {'bar','kPa','MPa','lbt/in2'};
            %                         app.UnitProp2DropDown.Items = app.allUnits.P;
            %
            %                     case 'Temperature'
            %                         %app.UnitProp2DropDown.Items = {[char(0176), 'C'],[char(0176), 'F'],'K'};
            %                         app.UnitProp2DropDown.Items = app.allUnits.T;
            %
            %                     case 'Specific Volume - v'
            %                         %app.UnitProp2DropDown.Items = {'m3/Kg','ft3/lb','MPa','lbt/in2'};                        app.UnitProp2DropDown.Items = app.allUnits.v;
            %                         app.UnitProp2DropDown.Items = app.allUnits.v;
            %
            %                     case 'Internal Energy - u'
            %                         %app.UnitProp2DropDown.Items = {'KJ/Kg','Btu/lb'};                        app.UnitProp2DropDown.Items = app.allUnits.v;
            %                         app.UnitProp2DropDown.Items = app.allUnits.uh;
            %
            %                     case 'Enthalpy - h'
            %                         %app.UnitProp2DropDown.Items = {'KJ/Kg','Btu/lb'};
            %                         app.UnitProp2DropDown.Items = app.allUnits.uh;
            %
            %                     case 'Entropy - s'
            %                         %app.UnitProp2DropDown.Items = {'KJ/Kg * K'};
            %                         app.UnitProp2DropDown.Items = app.allUnits.s;
            %
            %                     case 'quality - x'
            %                         app.UnitProp2DropDown.Items = {'No Unit'};
            %                 end
        end

        % Value changed function: ProPropertyNo1DropDown
        function ProPropertyNo1DropDownValueChanged(app, event)

            % First Property DropDown

            app.ProPropertyNo2DropDown.Items = {'Select','Pressure','Temperature','Specific Volume - v','Internal Energy - u','Enthalpy - h','Entropy - s','x'};
            Prop1 = app.ProPropertyNo1DropDown.Value;
            Prop2 = app.ProPropertyNo2DropDown.Value;

            %store the value of property 2 unit, then clear it
            %unit2 = app.ProUnitProp2DropDown.Value;
            %app.ProUnitProp2DropDown.Items = {};

            switch Prop1

                case 'Select'
                    app.ProUnitProp1DropDown.Items = {};

                case 'Pressure'
                    app.ProUnitProp1DropDown.Items = app.allUnits.P;

                    app.ProPropertyNo2DropDown.Items(2) = [];

                case 'Temperature'
                    app.ProUnitProp1DropDown.Items = app.allUnits.T;

                    app.ProPropertyNo2DropDown.Items(3) = [];

                case 'Specific Volume - v'
                    app.ProUnitProp1DropDown.Items = app.allUnits.v;

                    app.ProPropertyNo2DropDown.Items(4) = [];

                case 'Internal Energy - u'
                    app.ProUnitProp1DropDown.Items = app.allUnits.uh;

                    app.ProPropertyNo2DropDown.Items(5) = [];

                case 'Enthalpy - h'
                    app.ProUnitProp1DropDown.Items = app.allUnits.uh;

                    app.ProPropertyNo2DropDown.Items(6) = [];

                case 'Entropy - s'
                    app.ProUnitProp1DropDown.Items = app.allUnits.s;

                    app.ProPropertyNo2DropDown.Items(7) = [];

                case 'x'
                    app.ProUnitProp1DropDown.Items = {'No Unit'};
                    app.ProPropertyNo2DropDown.Items(8) = [];
            end

            %now we only need to kill the property 2 units if we chose
            %the previous second property as the first property now
            if strcmp(Prop1,Prop2)
                app.ProUnitProp2DropDown.Items = {};
            end

        end

        % Value changed function: ProPropertyNo2DropDown
        function ProPropertyNo2DropDownValueChanged(app, event)

            Prop2 = app.ProPropertyNo2DropDown.Value;

            switch Prop2

                case 'Select'
                    app.ProUnitProp2DropDown.Items = {};

                case 'Pressure'
                    app.ProUnitProp2DropDown.Items = app.allUnits.P;

                case 'Temperature'
                    app.ProUnitProp2DropDown.Items = app.allUnits.T;

                case 'Specific Volume - v'
                    app.ProUnitProp2DropDown.Items = app.allUnits.v;

                case 'Internal Energy - u'
                    app.ProUnitProp2DropDown.Items = app.allUnits.uh;

                case 'Enthalpy - h'
                    app.ProUnitProp2DropDown.Items = app.allUnits.uh;

                case 'Entropy - s'
                    app.ProUnitProp2DropDown.Items = app.allUnits.s;

                case 'x'
                    app.ProUnitProp2DropDown.Items = {'No Unit'};

            end


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            %             format(app.UITable,'long')

            % Table List
            %app.UITable.ColumnName = {'Phase','P [bar]',['T [', char(0176), 'C]'],'v [m3/Kg]','u [KJ/Kg]','h [KJ/Kg]','s [KJ/Kg * K]','x'};
            app.UITable.ColumnName = {'Phase',['P ',app.defaultUnits.P],['T ', char(0176), app.defaultUnits.T],['v ',app.defaultUnits.v],['u ',app.defaultUnits.uh],['h ',app.defaultUnits.uh],['s ',app.defaultUnits.s],'x'};


            %HERE This needs to change to reflect new units

            % First Property
            Property1 = app.PropertyNo1DropDown.Value;
            Value1 = app.Prop1EditField.Value;

            switch Property1

                case 'Pressure'
                    Prop1 = 'P';
                    %app.UITable.ColumnName{2}=  ['P [',app.UnitProp1DropDown.Value,']'];

                case 'Temperature'
                    Prop1 = 'T';
                    %app.UITable.ColumnName{3}=  ['T [',app.UnitProp1DropDown.Value,']'];

                case 'Specific Volume - v'
                    Prop1 = 'v';
                    %app.UITable.ColumnName{4}=  ['v [',app.UnitProp1DropDown.Value,']'];
                case 'Internal Energy - u'
                    Prop1 = 'u';
                    %app.UITable.ColumnName{5}=  ['u [',app.UnitProp1DropDown.Value,']'];
                case 'Enthalpy - h'
                    Prop1 = 'h';
                    %app.UITable.ColumnName{6}=  ['h [',app.UnitProp1DropDown.Value,']'];

                case 'Entropy - s'
                    Prop1 = 's';
                    %app.UITable.ColumnName{6}=  ['s [',app.UnitProp1DropDown.Value,']'];

                case 'x'
                    Prop1 = 'x';


            end


            % Second Property
            Property2 = app.PropertyNo2DropDown.Value;
            Value2 = app.Prop2EditField.Value;

            switch Property2
                %OVERRIDE - instead of changing units based on what is
                %selected, this will always output in the default book units
                case 'Pressure'
                    Prop2 = 'P';
                    %app.UITable.ColumnName{2}=  ['P [',app.UnitProp2DropDown.Value,']'];

                case 'Temperature'
                    Prop2 = 'T';
                    %app.UITable.ColumnName{3}=  ['T [',app.UnitProp2DropDown.Value,']'];

                case 'Specific Volume - v'
                    Prop2 = 'v';
                    %app.UITable.ColumnName{4}=  ['v [',app.UnitProp2DropDown.Value,']'];
                case 'Internal Energy - u'
                    Prop2 = 'u';
                    %app.UITable.ColumnName{5}=  ['u [',app.UnitProp2DropDown.Value,']'];
                case 'Enthalpy - h'
                    Prop2 = 'h';
                    %app.UITable.ColumnName{6}=  ['h [',app.UnitProp2DropDown.Value,']'];
                case 'Entropy - s'
                    Prop2 = 's';
                    %app.UITable.ColumnName{6}=  ['s [',app.UnitProp1DropDown.Value,']'];
                case 'x'
                    Prop2 = 'x';
            end

            %             Value1
            %             Value2
            %

            % Before state identification, convert units
            % this needs to happen before the interpolation because the
            % property tables are in the default units
            Value1 = unitConvertFcn(Value1,app.UnitProp1DropDown.Value,'default');
            %testNew = unitConvertFcn(Value1,app.UnitProp1DropDown.Value,'default')
            Value2 = unitConvertFcn(Value2,app.UnitProp2DropDown.Value,'default');


            state = PropertyCalculateSafe(Prop1,Value1,Prop2,Value2,app.Table,app.Critical);
            PropertyData = {state.State,state.P,state.T,state.v,state.u,state.h,state.s,state.x};

            %
            %            % State Identification
            %            if strcmp(Prop1,'x') || strcmp(Prop2,'x')
            %            State = 'Saturated';
            %            else
            %            SampleData= StateDetect(Prop1,Value1,Prop2,Value2,app.Table);
            %            State = SampleData.State;
            %            end
            %
            %
            %                switch State
            %
            %                case 'Saturated'
            %
            %                    if strcmp(Prop1,'x') || strcmp(Prop2,'x')
            %                        if strcmp(Prop1,'x')
            %                        SatState = XSaturated(Value1,Prop2,Value2,app.Table);
            %                        else
            %                        SatState = XSaturated(Value2,Prop1,Value1,app.Table);
            %                        end
            %                        PropertyData = {'Saturated',SatState.P,SatState.T,SatState.v,SatState.u,SatState.h,SatState.s,SatState.x};
            %                    else
            %                        SatState = StateDetect(Prop1,Value1,Prop2,Value2,app.Table);
            %                        PropertyData = {'Saturated',SatState.P,SatState.T,SatState.v,SatState.u,SatState.h,SatState.s,SatState.x};
            %
            %                    end
            %
            %                 case 'SuperHeat'
            %
            %                     if strcmp(Prop1,'P') || strcmp(Prop2,'P')
            %                         if strcmp(Prop1,'P')
            %                             SuperState = SuperHeat(Value1,Prop2,Value2,app.Table);
            %                         else
            %                             SuperState = SuperHeat(Value2,Prop1,Value1,app.Table);
            %                         end
            %                         PropertyData = {'SuperHeat',SuperState.P,SuperState.T,SuperState.v,SuperState.u,SuperState.h,SuperState.s,'N/A'};
            %                     else
            %                         SuperState = SuperHeatR(Prop1,Value1,Prop2,Value2,app.Table);
            %                         PropertyData = {'SuperHeat',SuperState.P,SuperState.T,SuperState.v,SuperState.u,SuperState.h,SuperState.s,'N/A'};
            %                     end
            %
            %                 case 'SubCooled'
            %
            %                     if strcmp(Prop1,'P') || strcmp(Prop2,'P')
            %                         if strcmp(Prop1,'P')
            %                             SubState = SubCooled(Value1,Prop2,Value2,app.Table);
            %                         else
            %                             SubState = SubCooled(Value2,Prop1,Value1,app.Table);
            %                         end
            %                         PropertyData = {'SubCooled',SubState.P,SubState.T,SubState.v,SubState.u,SubState.h,SubState.s,'N/A'};
            %                     else
            %                         SubState = SubCooledR(Prop1,Value1,Prop2,Value2,app.Table);
            %                         PropertyData = {'SubCooled',SubState.P,SubState.T,SubState.v,SubState.u,SubState.h,SubState.s,'N/A'};
            %                     end
            %
            %                end

            %                if strcmp(State,'Saturated')
            %
            %                    if SatState.x == 1
            %                        PropertyData{1} = 'Saturated Vapor';
            %                    elseif SatState.x ==0
            %                        PropertyData{1} = 'Saturated Liquid';
            %                    else
            %                        PropertyData{1} = 'Mixture';
            %                    end
            %
            %                end


            %now, before we pass it back to the table, we need to
            %convert back to the input units
            %PropertyData

            %PropertyData = {'SubCooled',SubState.P,SubState.T,SubState.v,SubState.u,SubState.h,SubState.s,'NaN'};

            %PropertyData{2} = unitConvertFcn(PropertyData{2},'default')


            %                   Columns 1 through 5
            %
            %     {'SubCooled'}    {[68.9476]}    {[200]}    {[0.0012]}    {[847.3914]}
            %
            %   Columns 6 through 8
            %
            %     {[855.3590]}    {[2.3210]}    {'NaN'}
            %
            %
            %

            app.UITable.Data = PropertyData;


        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            %this is analogous to the calculateButtonPushed?
            %yes, because it is Add / Update

            % Table List
            app.ProStateUITable.ColumnName = {'State Label','Fluid','Phase',['P [',app.defaultUnits.P, ']'],['T [',app.defaultUnits.T,']'],['v [',app.defaultUnits.v, ']'],['u [',app.defaultUnits.uh, ']'],['h [',app.defaultUnits.uh, ']'],['s [',app.defaultUnits.s, ']'],'x'};

            %IF the process checkbox is on - currently unimplemented
            if app.ProcessCheckBox.Value
                Opt = app.ProcessDropDown.Value;

                switch Opt

                    %all this is not yet implemented
                    case 'Isobar'
                        %case app.Processes{1}
                        Property1 = 'Pressure';
                        Value1 = app.ProStateUITable.Data{end,4};

                    case 'Isotherm'
                        %case app.Processes{2}
                        Property1 = 'Temperature';
                        Value1 = app.ProStateUITable.Data{end,5};

                    case 'Constant Volume'
                        %case app.Processes{3}
                        Property1 = 'Specific Volume - v';
                        Value1 = app.ProStateUITable.Data{end,6};

                end

            else

                Property1 = app.ProPropertyNo1DropDown.Value;
                Value1 = app.ProProp1EditField.Value;

            end

            % First Property

            switch Property1

                case 'Pressure'
                    Prop1 = 'P';
                    %app.ProStateUITable.ColumnName{3}=  ['P [',app.ProUnitProp1DropDown.Value,']'];

                case 'Temperature'
                    Prop1 = 'T';
                    %app.ProStateUITable.ColumnName{4}=  ['T [',app.ProUnitProp1DropDown.Value,']'];

                case 'Specific Volume - v'
                    Prop1 = 'v';
                    %app.ProStateUITable.ColumnName{5}=  ['v [',app.ProUnitProp1DropDown.Value,']'];

                case 'Internal Energy - u'
                    Prop1 = 'u';
                    %app.ProStateUITable.ColumnName{6}=  ['u [',app.ProUnitProp1DropDown.Value,']'];

                case 'Enthalpy - h'
                    Prop1 = 'h';
                    %app.ProStateUITable.ColumnName{7}=  ['h [',app.ProUnitProp1DropDown.Value,']'];

                case 'Entropy - s'
                    Prop1 = 's';
                    %app.ProStateUITable.ColumnName{8}=  ['s [',app.ProUnitProp1DropDown.Value,']'];

                case 'x'
                    Prop1 = 'x';

            end

            % Second Property
            Property2 = app.ProPropertyNo2DropDown.Value;
            Value2 = app.ProProp2EditField.Value;

            switch Property2

                case 'Pressure'
                    Prop2 = 'P';
                    %app.ProStateUITable.ColumnName{3}=  ['P [',app.ProUnitProp2DropDown.Value,']'];

                case 'Temperature'
                    Prop2 = 'T';
                    %app.ProStateUITable.ColumnName{4}=  ['T [',app.ProUnitProp2DropDown.Value,']'];

                case 'Specific Volume - v'
                    Prop2 = 'v';
                    %app.ProStateUITable.ColumnName{5}=  ['v [',app.ProUnitProp2DropDown.Value,']'];
                case 'Internal Energy - u'
                    Prop2 = 'u';
                    %app.ProStateUITable.ColumnName{6}=  ['u [',app.ProUnitProp2DropDown.Value,']'];
                case 'Enthalpy - h'
                    Prop2 = 'h';
                    %app.ProStateUITable.ColumnName{7}=  ['h [',app.ProUnitProp2DropDown.Value,']'];
                case 'Entropy - s'
                    Prop2 = 's';
                    %app.ProStateUITable.ColumnName{8}=  ['s [',app.ProUnitProp2DropDown.Value,']'];

                case 'x'
                    Prop2 = 'x';
            end


            % Before state identification, convert units
            % this needs to happen before the interpolation because the
            % property tables are in the default units
            Value1Converted = unitConvertFcn(Value1,app.ProUnitProp1DropDown.Value,'default',app.debug);
            %testNew = unitConvertFcn(Value1,app.UnitProp1DropDown.Value,'default')
            Value2Converted = unitConvertFcn(Value2,app.ProUnitProp2DropDown.Value,'default',app.debug);
            %Value2
            %a = app.ProUnitProp2DropDown.Value

            %calculate properties
            PropertyData = PropertyCalculateSafe(Prop1,Value1Converted,Prop2,Value2Converted,app.Table,app.Critical,app.debug);
            if strcmp(PropertyData{1},'DNE')
                UIerror = warndlg('Data out of bounds!','Error');
            end


            %add default state names as alphabet
            if app.StateLablel.Value
                StateLable = app.StateLablel.Value;
            else

                currentLength = size(app.ProStateUITable.Data);
                StateLable = char('a'+currentLength(1)); % height of the table
            end


            currentSize = size(app.ProStateUITable.Data,1); %height of the current table

            y = app.inputHistory(:,1);
            %x = StateLable
            %y = char(app.inputHistory(:,1))
            matchesAny = 0;
            for j = 1:currentSize
                current = y(j);
                %ystr = char(current);
                %ynospace =
                test = strcmp(StateLable,current);

                if test %the current state label matches one in the table
                    matchesAny = 1; %it does match one of them - so don't do the default later (which is add a new row)
                    %since it matches, we want to update the values we have
                    %in the registry
                    app.inputHistory(j,1:8) = [{StateLable},{app.ProPropertyNo1DropDown.Value},{Value1},{app.ProUnitProp1DropDown.Value}...
                        {app.ProPropertyNo2DropDown.Value},{Value2},{app.ProUnitProp2DropDown.Value},{app.WorkingFluidDropdown.Value}];

                end

            end

            if ~matchesAny %it didn't match any of the existing states in the registry, so add one
                %we also have the label for the state
                %Now we have both properties and their values. Let's store them
                %in a register for future reference

                %should be zero at start
                %app.inputHistory(currentSize+1,1) = [{Prop1}];
                %actually we don't want the Prop1, Prop2, we want the values of
                %those dropdown fields

                app.inputHistory(currentSize+1,1:8) = [{StateLable},{app.ProPropertyNo1DropDown.Value},{Value1},{app.ProUnitProp1DropDown.Value}...
                    {app.ProPropertyNo2DropDown.Value},{Value2},{app.ProUnitProp2DropDown.Value},{app.WorkingFluidDropdown.Value}];

            end

            if app.debug
                disp(app.inputHistory)
            end
            %Format: inputHistory = {'statename','p1',val1,unit1,'p2',val2,unit2,fluidType}

            % add statelabel and working fluid to PropertyData
            PropertyData = [{StateLable},{app.WorkingFluidDropdown.Value},PropertyData];
            if app.debug
                disp(PropertyData)
            end

            % add working fluid to PropertyData
            IsUpdate = false;

            if isempty(app.ProStateUITable.Data)
                app.ProStateUITable.Data = [app.ProStateUITable.Data;PropertyData];
            else
                for i = 1:size(app.ProStateUITable.Data,1)
                    if strcmp(StateLable,app.ProStateUITable.Data{i,1})
                        app.ProStateUITable.Data(i,:) = PropertyData;
                        IsUpdate = true;
                        break
                    end
                end

                %PropertyData
                if ~IsUpdate %no need to update, simply add the new data
                    app.ProStateUITable.Data = [app.ProStateUITable.Data;PropertyData];
                end

            end

            app.StartingStateDropDown.Items = {'Select',app.ProStateUITable.Data{:,1}};

            StartingList = app.ProStateUITable.Data(:,1);
            app.StartingStateDropDown.Items = [{'Select'},StartingList(:)'];
            app.FinalStateDropDown.Items = [{'Select'},StartingList(:)'];

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %% ------------------Initialization section------------------%%
            %reset old graphs and start new ones
            cla(app.UIAxes,'reset')
            cla(app.Plot3D,'reset')
            grid(app.Plot3D,'on')
            hold(app.UIAxes,'on')
            hold(app.Plot3D,'on')

            %scrub the legendData so that it updates if they uncheck
            %something
            app.legendData = {}; %legend data for T-v, P-v, T-s
            app.legendData3D = {};

            %plot type - T-V, P-V, T-S
            Opt = app.PlotDropDown.Value;
            %app.plotTypes = {'P - V', 'T - V', 'T - S'};

            %update / reset plot limits based on current working fluid
            if strcmp(app.PlotFluidDropDown.Value,app.workingFluids{1})
                app.plotLimits = app.plotLimitsWater;
                [app.Table,app.Critical] = GenTableNew();

                %elseif strcmp(value,"R134a")
            elseif strcmp(app.PlotFluidDropDown.Value,app.workingFluids{2})
                app.plotLimits = app.plotLimitsR134a;
                [app.Table,app.Critical] = GenTableR134a();

            else
                disp("Invalid working fluid")
            end

            %% -------------------- 3D Plot Section ---------------------%%
            %             surf(app.DataPVT.Sat.v,app.DataPVT.Sat.T,app.DataPVT.Sat.P,'FaceColor',[0.6078,0.7882,0.8784],'EdgeColor','none','Parent',app.Plot3D);
            hold(app.Plot3D,'on')
            title(app.Plot3D,['Pressure - Temperature - Specific Volume Map for ',app.PlotFluidDropDown.Value])
            xlabel(app.Plot3D,app.ProStateUITable.ColumnName{6})
            ylabel(app.Plot3D,app.ProStateUITable.ColumnName{5})
            zlabel(app.Plot3D,app.ProStateUITable.ColumnName{4})
            view(app.Plot3D,[45 45])
            set(app.Plot3D,'XScale','log')

            plot3(app.Table.Sat.vf,app.Table.Sat.T,app.Table.Sat.P,'-r','Parent',app.Plot3D)
            plot3(app.Table.Sat.vg,app.Table.Sat.T,app.Table.Sat.P,'-r','Parent',app.Plot3D)
            %             surf(app.DataPVT.Sup.v,app.DataPVT.Sup.T,app.DataPVT.Sup.P,'FaceColor',[ 0.8118,0.9020,0.9255],'EdgeColor','none','Parent',app.Plot3D);
            %             surf(app.DataPVT.Sub.v,app.DataPVT.Sub.T,app.DataPVT.Sub.P,'FaceColor',[ 0.3922,0.5843,0.7647],'EdgeColor','none','Parent',app.Plot3D);
            %             surf(app.DataPVT.SV.v,app.DataPVT.SV.T,app.DataPVT.SV.P,'FaceColor',[0.196,0.5961,0.7647],'EdgeColor','none','Parent',app.Plot3D);
            %             set(app.Plot3D,'YScale','log')

            %plot states
            % plot states on 3D Plot
            app.proStates = zeros(size(app.ProStateUITable.Data,1),3);

            for i = 1:size(app.ProStateUITable.Data,1)
                % check if same fluid - needs a fluid dropdown for the 3d
                % plot

                %convert units
                %3,4,5 are pressure,temperature and specific volume
                %we could convert units for the benefit of the 3D plot, but nah
                %             app.proStates(i,1) = unitConvertFcn(app.ProStateUITable.Data{i,3},'default','default');
                %             app.proStates(i,2) = unitConvertFcn(app.ProStateUITable.Data{i,4},'default','default');
                %             app.proStates(i,3) = unitConvertFcn(app.ProStateUITable.Data{i,5},'default','default');

                plot3(app.Plot3D,app.ProStateUITable.Data{i,6},app.ProStateUITable.Data{i,5},app.ProStateUITable.Data{i,4},'Ok','MarkerFaceColor','k')
                text(app.Plot3D,app.ProStateUITable.Data{i,6},app.ProStateUITable.Data{i,5},app.ProStateUITable.Data{i,4},['  ' app.ProStateUITable.Data{i,1}])
            end

            %% ----------------------- Iso-Lines ------------------------%%
            if app.IsoLinesCheckBox.Value %if isobars is unchecked, do nothing

                % for each value entered, bounds check against absolute
                % data bounds. This combined with automatic scoping should
                % mean the isolines only pull from table data
                %   done inside each type for loop

                % Isobar Lines
                if app.IsoBarCheckBox.Value
                    if ~isempty(app.IsoBarLineValue.Value)
                        IsoValues = str2double(strsplit(app.IsoBarLineValue.Value,','));
                        for i = 1:numel(IsoValues)

                            [localMin,localMax] = localBoundsCheck('P',IsoValues(i),app.Table,app.Critical,app.debug);

                            if app.debug
                                localMin
                                localMax
                            end

                            if isempty(localMin.v) || isempty(localMax.v)
                                fprintf("Iso value out of bounds")
                                UIerror = warndlg(['Isobar value out of data bounds: ',num2str(IsoValues(i))],'Error');
                            else
                                % add to the legend data
                                %the square brackets prevent it from interpreting
                                %the initially empty cell array as something it
                                %should keep, during the first concatenation
                                app.legendData = [app.legendData, [num2str(IsoValues(i)),' ',app.UnitIsoBarDropDown.Value]];
                                %b = app.legendData

                                %convert units after this
                                %Convert Units:
                                IsoValues(i) = unitConvertFcn(IsoValues(i),app.UnitIsoBarDropDown.Value,'default');
                                %convert to default, get the iso line data, then
                                %convert back to plot it

                                Out = IsoLine('P',IsoValues(i),app.Table,app.Critical,0,app.debug);
                                %Out = IsoBarLineNew(IsoValues(i),app.Table);
                                hold(app.UIAxes,'on')

                                %convert units to the figure's default units
                                Out.P = unitConvertFcn(Out.P,'default',app.UnitPlotYDropDown.Value,app.debug);
                                Out.v = unitConvertFcn(Out.v,'default',app.UnitPlotXDropDown.Value,app.debug);
                                Out.T = unitConvertFcn(Out.T,'default',app.UnitPlotYDropDown.Value,app.debug);
                                Out.s = unitConvertFcn(Out.s,'default',app.UnitPlotXDropDown.Value,app.debug);

                                % Plot Lines
                                switch Opt
                                    %case 'V - T'
                                    case app.plotTypes(2)
                                        plot(Out.v,Out.T,'Color',app.isoLineColors.Isobaric,'LineStyle','-','linewidth',app.isoLineWidth,'Parent',app.UIAxes);

                                        %case 'V - P'
                                    case app.plotTypes(1)
                                        plot(Out.v,Out.P,'Color',app.isoLineColors.Isobaric,'LineStyle','-','linewidth',app.isoLineWidth,'Parent',app.UIAxes);

                                        %case 'T - S'
                                    case app.plotTypes(3)
                                        plot(Out.s,Out.T,'Color',app.isoLineColors.Isobaric,'LineStyle','-','linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                                end
                            end
                        end
                    end
                end

                % IsoTherm Lines
                if app.IsoThermCheckBox.Value
                    if ~isempty(app.IsoThermValue.Value)

                        IsoValues = str2double(strsplit(app.IsoThermValue.Value,','));

                        for i = 1:numel(IsoValues)

                            [localMin,localMax] = localBoundsCheck('T',IsoValues(i),app.Table,app.Critical,app.debug);

                            if app.debug
                                localMin
                                localMax
                            end

                            if isempty(localMin.v) || isempty(localMax.v)
                                fprintf("Iso value out of bounds")
                                UIerror = warndlg(['Isotherm value out of data bounds: ',num2str(IsoValues(i))],'Error');
                            else
                                % add to the legend data
                                app.legendData = [app.legendData, [num2str(IsoValues(i)),' ',app.UnitIsoThermDropDown.Value]];
                                %b = app.legendData;

                                %Convert Units:
                                IsoValues(i) = unitConvertFcn(IsoValues(i),app.UnitIsoThermDropDown.Value,'default');
                                %convert to default, get the iso line data, then
                                %convert back to plot it

                                Out = IsoLine('T',IsoValues(i),app.Table,app.Critical,0,app.debug);
                                %Out = IsoThermLine(IsoValues(i),app.Table,app.Critical);
                                hold(app.UIAxes,'on')

                                %convert outputs
                                Out.P = unitConvertFcn(Out.P,'default',app.UnitPlotYDropDown.Value,app.debug);
                                Out.v = unitConvertFcn(Out.v,'default',app.UnitPlotXDropDown.Value,app.debug);
                                Out.T = unitConvertFcn(Out.T,'default',app.UnitPlotYDropDown.Value,app.debug);
                                Out.s = unitConvertFcn(Out.s,'default',app.UnitPlotXDropDown.Value,app.debug);

                                % Plot Lines
                                switch Opt
                                    %case 'V - T'
                                    case app.plotTypes(2)
                                        plot(Out.v,Out.T,'Color',app.isoLineColors.Isothermal,'LineStyle','-','linewidth',app.isoLineWidth,'Parent',app.UIAxes);

                                        %case 'V - P'
                                    case app.plotTypes(1)
                                        plot(Out.v,Out.P,'Color',app.isoLineColors.Isothermal,'LineStyle','-','linewidth',app.isoLineWidth,'Parent',app.UIAxes);

                                        %case 'T - S'
                                    case app.plotTypes(3)
                                        plot(Out.s,Out.T,'Color',app.isoLineColors.Isothermal,'LineStyle','-','linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                                end
                            end
                        end
                    end
                end

                % Isochoric lines
                if app.ConstantVolCheckBox.Value
                    if ~isempty(app.ConstantVolValue.Value)

                        IsoValues = str2double(strsplit(app.ConstantVolValue.Value,','));

                        for i = 1:numel(IsoValues)
                            [localMin,localMax] = localBoundsCheck('v',IsoValues(i),app.Table,app.Critical,app.debug);

                            if app.debug
                                localMin
                                localMax
                            end

                            if isempty(localMin.T) || isempty(localMax.T) || localMax.T < 1.01 * localMin.T%isempty(localMin.T) || isempty(localMax.T)
                                fprintf("Iso value out of bounds")
                                UIerror = warndlg(['Iso-volume value out of data bounds: ',num2str(IsoValues(i))],'Error');
                            else
                                % add to the legend data
                                app.legendData = [app.legendData, [num2str(IsoValues(i)),' ',app.UnitIsoVolDropDown.Value]];
                                %b = app.legendData;

                                %Convert Units:
                                IsoValues = unitConvertFcn(IsoValues,app.UnitIsoVolDropDown.Value,'default');
                                %convert to default, get the iso line data, then
                                %convert back to plot it

                                %Out = ConstVolLine(IsoValues(i),app.Table,app.Critical);
                                Out = IsoLine('v',IsoValues(i),app.Table,app.Critical,0,app.debug);

                                %convert outputs
                                Out.P = unitConvertFcn(Out.P,'default',app.UnitPlotYDropDown.Value,app.debug);
                                Out.v = unitConvertFcn(Out.v,'default',app.UnitPlotXDropDown.Value,app.debug);
                                Out.T = unitConvertFcn(Out.T,'default',app.UnitPlotYDropDown.Value,app.debug);
                                Out.s = unitConvertFcn(Out.s,'default',app.UnitPlotXDropDown.Value,app.debug);

                                hold(app.UIAxes,'on')
                                % Plot Lines
                                switch Opt
                                    %case 'V - T'
                                    case app.plotTypes(2)
                                        plot(Out.v,Out.T,'Color',app.isoLineColors.Isovolumetric,'LineStyle','-','linewidth',app.isoLineWidth,'Parent',app.UIAxes);

                                        %case 'V - P'
                                    case app.plotTypes(1)
                                        plot(Out.v,Out.P,'Color',app.isoLineColors.Isovolumetric,'LineStyle','-','linewidth',app.isoLineWidth,'Parent',app.UIAxes);

                                        %case 'T - S'
                                    case app.plotTypes(3)
                                        plot(Out.s,Out.T,'Color',app.isoLineColors.Isovolumetric,'LineStyle','-','linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                                end

                            end
                        end
                    end
                end

                % Isentropic Lines
                if app.IsentropicCheckBox.Value
                    if ~isempty(app.IsentropicValue.Value)

                        IsoValues = str2double(strsplit(app.IsentropicValue.Value,','));
                        for i = 1:numel(IsoValues)
                            [localMin,localMax] = localBoundsCheck('P',IsoValues(i),app.Table,app.Critical,app.debug);

                            if app.debug
                                localMin
                                localMax
                            end

                            if isempty(localMin.T) || isempty(localMax.T)
                                fprintf("Iso value out of bounds")
                                UIerror = warndlg(['Isentropic value out of data bounds: ',num2str(IsoValues(i))],'Error');
                            else
                                % add to the legend data
                                app.legendData = [app.legendData, [num2str(IsoValues(i)),' ',app.UnitIsentropicDropDown.Value]];
                                %b = app.legendData

                                %Convert Units:
                                IsoValues = unitConvertFcn(IsoValues,app.UnitIsentropicDropDown.Value,'default');
                                %convert to default, get the iso line data, then
                                %convert back to plot it

                                %Out = IsentropicLine(IsoValues(i),app.Table,app.Critical);
                                Out = IsoLine('s',IsoValues(i),app.Table,app.Critical,0,app.debug);

                                Out.P = unitConvertFcn(Out.P,'default',app.UnitPlotYDropDown.Value,app.debug);
                                Out.v = unitConvertFcn(Out.v,'default',app.UnitPlotXDropDown.Value,app.debug);
                                Out.T = unitConvertFcn(Out.T,'default',app.UnitPlotYDropDown.Value,app.debug);
                                Out.s = unitConvertFcn(Out.s,'default',app.UnitPlotXDropDown.Value,app.debug);

                                hold(app.UIAxes,'on')

                                % Plot Lines
                                switch Opt
                                    %case 'T - V'
                                    case app.plotTypes(2)
                                        %                                     plot(Out.v,Out.T,'Color',app.isoLineColors.Isentropic,'LineStyle',app.isoLineStyles{mod(i-1,numel(app.isoLineStyles))+1},'linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                                        plot(Out.v,Out.T,'Color',app.isoLineColors.Isentropic,'LineStyle','-','linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                                        %case 'V - P'
                                    case app.plotTypes(1)
                                        %                                     plot(Out.v,Out.P,'Color',app.isoLineColors.Isentropic,'LineStyle',app.isoLineStyles{mod(i-1,numel(app.isoLineStyles))+1},'linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                                        plot(Out.v,Out.P,'Color',app.isoLineColors.Isentropic,'LineStyle','-','linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                                        %case 'T - S'
                                    case app.plotTypes(3)
                                        %                                     plot(Out.s,Out.T,'Color',app.isoLineColors.Isentropic,'LineStyle',app.isoLineStyles{mod(i-1,numel(app.isoLineStyles))+1},'linewidth',app.isoLineWidth,'Parent',app.UIAxes);
                                        plot(Out.s,Out.T,'Color',app.isoLineColors.Isentropic,'LineStyle','-','linewidth',app.isoLineWidth,'Parent',app.UIAxes);

                                end
                            end
                        end
                    end
                end

            end

            %% --------------- Saturation curves and states--------------%%
            switch Opt %switch based on plot type
                case app.plotTypes(1)
                    %% P-v
                    axis(app.UIAxes,app.plotLimits.PV)
                    switch app.PlotFluidDropDown.Value
                        case 'Water'
                            title(app.UIAxes,"Pressure-Volume Map for Water-Steam")
                        case 'R134a'
                            title(app.UIAxes,"Pressure-Volume Map for R134a")
                    end
                    xlabel(app.UIAxes,['Specific volume [', app.UnitPlotXDropDown.Value,']'])
                    ylabel(app.UIAxes,['Pressure [', app.UnitPlotYDropDown.Value,']'])
                    hold(app.UIAxes,'on')
                    %app.legendData = [app.legendData,'P-V Saturated'];

                    %use the plot limits
                    xlim(app.UIAxes,unitConvertFcn(app.plotLimits.PV(1:2),'default',app.UnitPlotXDropDown.Value,app.debug))
                    ylim(app.UIAxes,unitConvertFcn(app.plotLimits.PV(3:4),'default',app.UnitPlotYDropDown.Value,app.debug))

                    %convert saturation curve units
                    vf = unitConvertFcn(app.Table.Sat.vf,'default',app.UnitPlotXDropDown.Value,app.debug);
                    vg = unitConvertFcn(app.Table.Sat.vg,'default',app.UnitPlotXDropDown.Value,app.debug);
                    press = unitConvertFcn(app.Table.Sat.P,'default',app.UnitPlotYDropDown.Value,app.debug);

                    %plot saturation curve
                    plot(vf,press,'b--',vg,press,'b--','linewidth',app.lineWidth,'Parent',app.UIAxes);

                    %initialize array for the states
                    app.proStates = zeros(size(app.ProStateUITable.Data,1),2);

                    for i = 1:size(app.ProStateUITable.Data,1)
                        %3 and 5 are pressure and specific volume
                        if strcmp(app.PlotFluidDropDown.Value,app.inputHistory{i,8}) %if the fluid matches
                            app.proStates(i,1) = unitConvertFcn(app.ProStateUITable.Data{i,6},'default',app.UnitPlotXDropDown.Value,app.debug);
                            app.proStates(i,2) = unitConvertFcn(app.ProStateUITable.Data{i,4},'default',app.UnitPlotYDropDown.Value,app.debug);

                            plot(app.UIAxes,app.proStates(i,1),app.proStates(i,2),'Ok','MarkerFaceColor','k')
                            text(app.UIAxes,app.proStates(i,1),app.proStates(i,2),['  ' app.ProStateUITable.Data{i,1}])
                        end
                    end


                case app.plotTypes(2)
                    %% T-v
                    %set scale and axis min / max
                    axis(app.UIAxes,app.plotLimits.TV)
                    switch app.PlotFluidDropDown.Value
                        case 'Water'
                            title(app.UIAxes,"Temperature-Volume Map for Water-Steam")
                        case 'R134a'
                            title(app.UIAxes,"Temperature-Volume Map for R134a")
                    end
                    xlabel(app.UIAxes,['Specific volume [', app.UnitPlotXDropDown.Value,']'])
                    ylabel(app.UIAxes,['Temperature [', app.UnitPlotYDropDown.Value,']'])
                    hold(app.UIAxes,'on')

                    %app.legendData = [app.legendData,'T-V Saturated'];

                    %adjust plot limits based on unit conversion from the
                    %default limits
                    %x axis
                    %app.plotLimits.TV(1:2) = unitConvertFcn(app.plotLimits.TV(1:2),'default',app.UnitPlotXDropDown.Value);
                    %y axis
                    %app.plotLimits.TV(3:4) = unitConvertFcn(app.plotLimits.TV(3:4),'default',app.UnitPlotYDropDown.Value);

                    %use the plot limits
                    xlim(app.UIAxes,unitConvertFcn(app.plotLimits.TV(1:2),'default',app.UnitPlotXDropDown.Value,app.debug))
                    ylim(app.UIAxes,unitConvertFcn(app.plotLimits.TV(3:4),'default',app.UnitPlotYDropDown.Value,app.debug))

                    %convert saturation curve units
                    vf = unitConvertFcn(app.Table.Sat.vf,'default',app.UnitPlotXDropDown.Value,app.debug);
                    vg = unitConvertFcn(app.Table.Sat.vg,'default',app.UnitPlotXDropDown.Value,app.debug);
                    temp = unitConvertFcn(app.Table.Sat.T,'default',app.UnitPlotYDropDown.Value,app.debug);

                    plot(vf,temp,'b--',vg,temp,'b--','linewidth',app.lineWidth,'Parent',app.UIAxes);

                    %initialize array for the states
                    app.proStates = zeros(size(app.ProStateUITable.Data,1),2);

                    for i = 1:size(app.ProStateUITable.Data,1)
                        %a = app.inputHistory
                        if strcmp(app.PlotFluidDropDown.Value,app.inputHistory{i,8}) %if the fluid matches
                            app.proStates(i,1) = unitConvertFcn(app.ProStateUITable.Data{i,6},'default',app.UnitPlotXDropDown.Value,app.debug);
                            app.proStates(i,2) = unitConvertFcn(app.ProStateUITable.Data{i,5},'default',app.UnitPlotYDropDown.Value,app.debug);

                            plot(app.UIAxes,app.proStates(i,1),app.proStates(i,2),'Ok','MarkerFaceColor','k')
                            text(app.UIAxes,app.proStates(i,1),app.proStates(i,2),['  ' app.ProStateUITable.Data{i,1}])
                        end
                    end

                case app.plotTypes(3)
                    %% T-s
                    %set scale and axis min / max
                    axis(app.UIAxes,app.plotLimits.TS)
                    %app.legendData = [app.legendData,'T-S Saturated'];
                    %title(app.UIAxes,app.plotTypes(3))
                    switch app.PlotFluidDropDown.Value
                        case 'Water'
                            title(app.UIAxes,"Temperature-Entropy Map for Water-Steam")
                        case 'R134a'
                            title(app.UIAxes,"Temperature-Entropy Map for R134a")
                    end
                    xlabel(app.UIAxes,['Specific entropy [', app.UnitPlotXDropDown.Value,']'])
                    ylabel(app.UIAxes,['Temperature [', app.UnitPlotYDropDown.Value,']'])
                    hold(app.UIAxes,'on')

                    %convert axis limits to the selected units. they start at the
                    %default units. The units of the plot, whether TS, PV, etc, are
                    %covered by the option dropdowns updating when the graph type
                    %is changed. So this should never cause conversion between
                    %m3/kg to J/kg*K for example
                    %                     %x axis
                    %                     app.plotLimits.TS(1:2) = unitConvertFcn(app.plotLimits.TS(1:2),'default',app.UnitPlotXDropDown.Value);
                    %                     %y axis
                    %                     app.plotLimits.TS(3:4) = unitConvertFcn(app.plotLimits.TS(3:4),'default',app.UnitPlotYDropDown.Value);
                    %
                    %use the plot limits
                    xlim(app.UIAxes,unitConvertFcn(app.plotLimits.TS(1:2),'default',app.UnitPlotXDropDown.Value,app.debug))
                    ylim(app.UIAxes,unitConvertFcn(app.plotLimits.TS(3:4),'default',app.UnitPlotYDropDown.Value,app.debug))


                    %convert saturation curve units
                    sf = unitConvertFcn(app.Table.Sat.sf,'default',app.UnitPlotXDropDown.Value,app.debug);
                    sg = unitConvertFcn(app.Table.Sat.sg,'default',app.UnitPlotXDropDown.Value,app.debug);
                    temp = unitConvertFcn(app.Table.Sat.T,'default',app.UnitPlotYDropDown.Value,app.debug);

                    %plot saturation curve
                    plot(sf,temp,'--b',sg,temp,'--b','linewidth',app.lineWidth,'Parent',app.UIAxes);

                    %initialize array for the states
                    app.proStates = zeros(size(app.ProStateUITable.Data,1),2);

                    for i = 1:size(app.ProStateUITable.Data,1)
                        if strcmp(app.PlotFluidDropDown.Value,app.inputHistory{i,8}) %if the fluid matches
                            %convert units
                            %4 and 8 are temperature and entropy
                            app.proStates(i,1) = unitConvertFcn(app.ProStateUITable.Data{i,9},'default',app.UnitPlotXDropDown.Value,app.debug);
                            app.proStates(i,2) = unitConvertFcn(app.ProStateUITable.Data{i,5},'default',app.UnitPlotYDropDown.Value,app.debug);

                            plot(app.UIAxes,app.proStates(i,1),app.proStates(i,2),'Ok','MarkerFaceColor','k')
                            text(app.UIAxes,app.proStates(i,1),app.proStates(i,2),['  ' app.ProStateUITable.Data{i,1}])
                        end
                    end

                case 'Select' % drop down option to reset graph
                    cla(app.UIAxes,'reset')
            end

            %% --------------------- Process lines ----------------------%%
            % the previous UI has a processes to plot selection.
            % if the "plot all processes" box is checked, set the list of
            % processes to plot to all of them. Otherwise only the ones
            % that are selected
            if app.AllProcessesCheckBox.Value
                ProcessList = app.ProcessListBox.Items;
            else
                ProcessList = app.ProcessListBox.Value;
            end

            %loop through the list of selected processes
            for k = 1:numel(ProcessList)

                ProcLabel = ProcessList{k};
                %app.legendData = [app.legendData, ProcLabel];
                %app.legendData3D = [app.legendData3D, ProcLabel];

                %note: all this "detect" code is kind of dumb but I don't
                %feel like rewriting it

                % Detecting the Process
                for i=1:size(app.ProUITable.Data,1)
                    if strcmp(ProcLabel,app.ProUITable.Data{i,1})
                        Start = app.ProUITable.Data{i,3};
                        Final = app.ProUITable.Data{i,4};
                        ProcType = app.ProUITable.Data{i,end};
                        procFluid = app.ProUITable.Data{i,2}; %pull fluid type of the process
                        break
                    end
                end

                % Detecting the Starting State of the Process
                for i=1:size(app.ProStateUITable.Data,1)
                    if strcmp(Start,app.ProStateUITable.Data{i,1})
                        vStart = app.ProStateUITable.Data{i,6};
                        PStart = app.ProStateUITable.Data{i,4};
                        TStart = app.ProStateUITable.Data{i,5};
                        sStart = app.ProStateUITable.Data{i,end-1};
                        break
                    end
                end

                % Detectin the Final State of the Process
                for i=1:size(app.ProStateUITable.Data,1)
                    if strcmp(Final,app.ProStateUITable.Data{i,1})
                        vFinal = app.ProStateUITable.Data{i,6};
                        PFinal = app.ProStateUITable.Data{i,4};
                        TFinal = app.ProStateUITable.Data{i,5};
                        sFinal = app.ProStateUITable.Data{i,end-1};

                        break
                    end
                end

                % Process Line(States) Calculation
                switch ProcType

                    %case 'Isobar'
                    case app.Processes{1}
                        ProcData = ProcessLine('P',PStart,vStart,vFinal,app.Table,app.Critical,0,app.debug)
                        %ProcData = ProcIsoLine('P',PStart,vStart,vFinal,app.Table,app.Critical,app.debug);

                    %case 'Isotherm'
                    case app.Processes{2}
                        ProcData = ProcessLine('T',TStart,vStart,vFinal,app.Table,app.Critical,0,app.debug)
                        %ProcData = ProcIsoLine('T',TStart,vStart,vFinal,app.Table,app.Critical,app.debug);

                    %case 'Constant Volume'
                    case app.Processes{3}
                        ProcData = ProcessLine('v',vStart,TStart,TFinal,app.Table,app.Critical,0,app.debug)
                        %ProcData = ProcIsoLine('v',vStart,PStart,PFinal,app.Table,app.Critical,app.debug);

                    %case 'Isentropic'
                    case app.Processes{4}
                        ProcData = ProcessLine('s',sStart,TStart,TFinal,app.Table,app.Critical,0,app.debug)
                        %ProcData = ProcIsoLine('s',sStart,vStart,vFinal,app.Table,app.Critical,app.debug);

                        %case 'Polytropic'
                        %NOT YET IMPLEMENTED

                    case 'Unknown'
                        ProcData.v = [vStart,vFinal];
                        ProcData.T = [TStart,TFinal];
                        ProcData.P = [PStart,PFinal];
                        ProcData.s = [sStart,sFinal];
                end

                %Before plotting, convert units
                %be mindful, as always, that if this will convert all of
                %the values whether or not they make sense for that plot
                %for instance if L/kg was selected for a P-v plot, the
                %entropy would get converted from m3/kg to L/kg (or
                %multiplied by that factor). Again, shouldn't matter as it
                %isn't plotted

                ProcData.v = unitConvertFcn(ProcData.v,'default',app.UnitPlotXDropDown.Value,app.debug);
                ProcData.T = unitConvertFcn(ProcData.T,'default',app.UnitPlotYDropDown.Value,app.debug);
                ProcData.P = unitConvertFcn(ProcData.P,'default',app.UnitPlotYDropDown.Value,app.debug);
                ProcData.s = unitConvertFcn(ProcData.s,'default',app.UnitPlotXDropDown.Value,app.debug);


                if strcmp(procFluid,app.PlotFluidDropDown.Value)% || strcmp(procFluid,app.mixedFluidProcessLabel)
                    %fluid is the same, or mixed. Plot the process

                    % Plot Process Line, switch based on current plot type
                    switch Opt
                        case app.plotTypes(1)
                            %P-v
                            plot(ProcData.v,ProcData.P,':k','linewidth',app.processLineWidth,'Parent',app.UIAxes);
                        case app.plotTypes(2)
                            %T-v
                            plot(ProcData.v,ProcData.T,':k','linewidth',app.processLineWidth,'Parent',app.UIAxes);
                        case app.plotTypes(3)
                            %T-s
                            plot(ProcData.s,ProcData.T,':k','linewidth',app.processLineWidth,'Parent',app.UIAxes);
                    end

                    %plot processes on 3D plot
                    plot3(ProcData.v,ProcData.T,ProcData.P,':k','linewidth',app.processLineWidth,'Parent',app.Plot3D);

                else
                    %process fluid is different than selected. Do not plot
                end

            end
            %% ---------------- Final section ---------------------------%%
            %if it is not a T-s, then set log scale on specific volume
            if ~strcmp(Opt,app.plotTypes(3))
                set(app.UIAxes,'XScale','log')
            end

            box(app.UIAxes,'on')
            set(app.UIAxes,'linewidth',app.axisWidth,'FontSize',app.fontSizeFigure)
            grid(app.UIAxes,'on')

            %add a legend, at least for the normal plot (3D later?)
            if ~isempty(app.legendData)
                legend(app.UIAxes,app.legendData,'location','northwest')
            end

        end

        % Value changed function: ProcessCheckBox
        function ProcessCheckBoxValueChanged(app, event)

            ProcessCheck = app.ProcessCheckBox.Value;

            switch  ProcessCheck


                case true
                    app.ProPropertyNo1DropDown.Enable = 0;
                    app.ProProp1EditField.Enable = 0;
                    app.ProUnitProp1DropDown.Enable = 0;
                    app.ProcessDropDown.Enable = 1;
                    app.PropertyNo1Label.Visible = 0;
                    app.Label.Text = 'Property';


                case false
                    app.ProPropertyNo1DropDown.Enable = 1;
                    app.ProProp1EditField.Enable = 1;
                    app.ProUnitProp1DropDown.Enable = 1;
                    app.ProcessDropDown.Enable = 0;
                    app.PropertyNo1Label.Visible = 1;
                    app.Label.Text = 'Property No.2';

            end


        end

        % Value changed function: ProcessDropDown
        function ProcessDropDownValueChanged(app, event)
            ProcessCheck = app.ProcessCheckBox.Value;


            % First Property DropDown

            app.ProPropertyNo2DropDown.Items = {'Select','Pressure','Temperature','Specific Volume - v','Specific Internal Energy - u','Specific Enthropy - h','x'};
            Prop = app.ProcessDropDown.Value;


            if ProcessCheck

                switch Prop

                    case 'Select'
                        app.ProUnitProp1DropDown.Items = {};

                    case 'Isobar'
                        app.ProPropertyNo2DropDown.Items(2) = [];

                    case 'Isotherm'
                        app.ProPropertyNo2DropDown.Items(3) = [];

                    case 'Constant Volume'
                        app.ProPropertyNo2DropDown.Items(4) = [];

                    case 'Specific Internal Energy - u'
                        app.ProPropertyNo2DropDown.Items(5) = [];

                    case 'Specific Enthropy - h'
                        app.ProPropertyNo2DropDown.Items(6) = [];

                    case 'x'
                        app.ProPropertyNo2DropDown.Items(7) = [];
                end

            end


        end

        % Value changed function: IsoLinesCheckBox
        function IsoLinesCheckBoxValueChanged(app, event)
            Flag = app.IsoLinesCheckBox.Value;

            if Flag
                app.IsoBarLineValue.Enable = 1;
                app.IsoBarCheckBox.Enable = 1;

                app.IsoThermCheckBox.Enable = 1;
                app.IsoThermValue.Enable = 1;

                app.ConstantVolCheckBox.Enable = 1;
                app.ConstantVolValue.Enable = 1;

                app.IsentropicCheckBox.Enable = 1;
                app.IsentropicValue.Enable = 1;

            else
                app.IsoBarLineValue.Enable = 0;
                app.IsoBarCheckBox.Enable = 0;

                app.IsoThermCheckBox.Enable = 0;
                app.IsoThermValue.Enable = 0;

                app.ConstantVolCheckBox.Enable = 0;
                app.ConstantVolValue.Enable = 0;

                app.IsentropicCheckBox.Enable = 0;
                app.IsentropicValue.Enable = 0;
            end

        end

        % Cell selection callback: ProStateUITable
        function ProStateUITableCellSelection(app, event)
            app.StateInd = event.Indices;

            if isempty (app.StateInd)
                %fprintf("Empty event") %no need to do anything, eliminates a bug
            else
                %a = app.StateInd

                %the user has selected a cell in the table that has the
                %data
                %lets go back and update the inputs such that they're the
                %same as when the user input that state. This makes it
                %easier to revise existing states
                app.StateLablel.Value = app.ProStateUITable.Data{app.StateInd(1),1};


                %inputHistory = {'p1',val1,unit1,'p2',val2,unit2,fluidType}
                if app.debug
                    disp(app.inputHistory)
                end


                %the numeric fields need to be de-celled to be set. the
                %text fields need to stay in cells in order to match what
                %is in the dropdown.Items
                %a = app.ProPropertyNo1DropDown.Items
                app.ProPropertyNo1DropDown.Value = app.inputHistory(app.StateInd(1),2);
                app.ProProp1EditField.Value = app.inputHistory{app.StateInd(1),3};

                ProPropertyNo1DropDownValueChanged(app,0)
                %doesn't automatically get called when the value is set to change, only when the user changes it


                if app.debug %probably not necessary even for general debugging
                    %                     c = app.ProPropertyNo2DropDown.Items
                    %                     d = app.inputHistory(app.StateInd(1),5)
                end

                app.ProPropertyNo2DropDown.Value = app.inputHistory(app.StateInd(1),5);
                app.ProProp2EditField.Value = app.inputHistory{app.StateInd(1),6};


                %the property may have changed, so we need to update the
                %items in the unit dropdowns. Otherwise the units in the
                %register will not match any of the items in the dropdown
                ProPropertyNo1DropDownValueChanged(app)
                ProPropertyNo2DropDownValueChanged(app)


                app.ProUnitProp1DropDown.Value = app.inputHistory(app.StateInd(1),4);

                app.ProUnitProp2DropDown.Value = app.inputHistory(app.StateInd(1),7);

                app.WorkingFluidDropdown.Value = app.inputHistory(app.StateInd(1),8);

            end

        end

        % Cell selection callback: ProUITable
        function ProUITableCellSelection(app, event)
            % get the table index that is selected
            app.ProInd = event.Indices;

            if ~isempty(app.ProInd)
                %need to first update and make sure the .Items properties are
                %all right
                % Update the items in the starting and final state dropdown
                StartingList = app.ProStateUITable.Data(:,1);
                app.StartingStateDropDown.Items = [{'Select'},StartingList(:)'];
                app.FinalStateDropDown.Items = [{'Select'},StartingList(:)'];

                %update the state label
                app.ProcessLabelEditField.Value = app.ProUITable.Data{app.ProInd(1),1};

                %set back up the states as starting and final
                app.StartingStateDropDown.Value = app.ProUITable.Data{app.ProInd(1),3};
                app.FinalStateDropDown.Value = app.ProUITable.Data{app.ProInd(1),4};
            end

        end

        % Value changed function: StartingStateDropDown
        function StartingStateDropDownValueChanged(app, event)

            StartingList = app.ProStateUITable.Data(:,1);
            app.FinalStateDropDown.Items = [{'Select'},StartingList(:)'];

            for i = 2:size(app.ProStateUITable.Data,1)

                if strcmp(app.FinalStateDropDown.Items{i},app.StartingStateDropDown.Value)
                    app.FinalStateDropDown.Items(i)=[];
                    %b = app.FinalStateDropDown.Items(i)
                    break
                end

            end


        end

        % Button pushed function: ProcessAddButton
        function ProcessAddButtonPushed(app, event)

            IsUpdate = false;

            %is this detecting whether an existing process entry is being
            %updated, vs. a new one being added?
            I = zeros(1,size(app.ProUITable.Data,1));
            for i = 1:size(app.ProUITable.Data,1)
                if strcmp(app.StartingStateDropDown.Value,app.ProUITable.Data{i,3})
                    I(i) = i;
                end
            end
            I(I==0) = [];
            for j = 1:numel(I)

                if strcmp(app.FinalStateDropDown.Value,app.ProUITable.Data{I(j),4})

                    I_Index = I(j);
                    IsUpdate = true;
                    break
                end
            end


            % get name for process
            if ~strcmp(app.ProcessDropDownNew.Value,'Select')
                %                 ProcessName = app.UnknownProcessNameEditField.Value;
                %             else
                ProcessType = app.ProcessDropDownNew.Value;
            end %else, it breaks, so that better not happen

            %assign a default name to processes if it isn't specified
            if isempty(app.ProcessLabelEditField.Value)
                ProcessLabel = strcat(app.StartingStateDropDown.Value,'-',app.FinalStateDropDown.Value);
            else
                ProcessLabel = app.ProcessLabelEditField.Value;
            end

            %get working fluid of process - or label it mixed
            Start = app.StartingStateDropDown.Value;
            Final = app.FinalStateDropDown.Value;

            % Detecting the Starting State of the Process
            for i=1:size(app.ProStateUITable.Data,1)
                if strcmp(Start,app.ProStateUITable.Data{i,1})
                    fluidStart = app.ProStateUITable.Data{i,2};
                    break
                end
            end

            % Detectin the Final State of the Process
            for i=1:size(app.ProStateUITable.Data,1)
                if strcmp(Final,app.ProStateUITable.Data{i,1})
                    fluidEnd = app.ProStateUITable.Data{i,2};
                    break
                end
            end

            if strcmp(fluidStart,fluidEnd) %same fluid, label as such
                processFluid = fluidStart;
            else
                processFluid = app.mixedFluidProcessLabel;
            end

            %PropertyData = {app.ProcessLabelEditField.Value,app.StartingStateDropDown.Value,app.FinalStateDropDown.Value,ProcessName};
            %PropertyData = {ProcessLabel,app.StartingStateDropDown.Value,app.FinalStateDropDown.Value,ProcessType};
            PropertyData = {ProcessLabel,processFluid,app.StartingStateDropDown.Value,app.FinalStateDropDown.Value,ProcessType};

            if IsUpdate
                app.ProUITable.Data(I_Index,:) = PropertyData;
            else
                app.ProUITable.Data = [app.ProUITable.Data;PropertyData];
            end


            %             % Make Process List
            %             app.ProcessLabelPlot.Items = {'Select',app.ProUITable.Data{:,1}};

            % Update ListBox
            app.ProcessListBox.Items = app.ProUITable.Data(:,1);

            % Update the items in the starting and final state dropdown
            StartingList = app.ProStateUITable.Data(:,1);
            app.StartingStateDropDown.Items = [{'Select'},StartingList(:)'];
            app.FinalStateDropDown.Items = [{'Select'},StartingList(:)'];

            %set starting and final dropdown values to Select
            app.StartingStateDropDown.Value = 'Select';
            app.FinalStateDropDown.Value = 'Select';


        end

        % Value changed function: ProcessDropDownNew
        function ProcessDropDownNewValueChanged(app, event)
            %             value = app.ProcessDropDownNew.Value;

            %             if strcmp(value,'Unknown')
            %                 app.UnknownProcessNameEditField.Enable = 1;
            % %                 app.ProcessNameLabel.Enable = 1;
            %
            %             else
            %                 app.UnknownProcessNameEditField.Enable = 0;
            % %                 app.ProcessNameLabel.Enable = 0;
            %             end

        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            %If they are pressing the remove button, they should already
            %have selected a cell in the table. If they haven't we should
            %have a break case, or it simply should do nothing
            %
            %             a = app.StateInd(1) %this is the row that we are deleting
            %
            %             b = size(app.ProStateUITable.Data)
            %             app.ProStateUITable.Data(a,:)=[];


            app.ProStateUITable.Data(app.StateInd(1),:)=[];
            app.StateLablel.Value = ''; %?? works I think
            %b = app.inputHistory

            app.inputHistory(app.StateInd(1),:) = [];

            %c = app.inputHistory


            StartingList = app.ProStateUITable.Data(:,1);
            app.StartingStateDropDown.Items = [{'Select'},StartingList(:)'];

            %call value changed to update the other one? - yes this is
            %necessary and does in fact make it work
            app.StartingStateDropDownValueChanged();

            % Update ListBox - I don't know what this is
            app.ProcessListBox.Items = app.ProStateUITable.Data(:,1);

        end

        % Button pushed function: ProRemoveButton
        function ProRemoveButtonPushed(app, event)
            %If they are pressing the remove button, they should already
            %have selected a cell in the table. If they haven't we should
            %have a break case, or it simply should do nothing

            app.ProUITable.Data(app.ProInd(1),:) = [];

            % Update ListBox - final steo
            app.ProcessListBox.Items = app.ProUITable.Data(:,1);

            app.ProcessLabelEditField.Value = '';

            % Update the items in the starting and final state dropdown
            StartingList = app.ProStateUITable.Data(:,1);
            app.StartingStateDropDown.Items = [{'Select'},StartingList(:)'];
            app.FinalStateDropDown.Items = [{'Select'},StartingList(:)'];

            %set starting and final dropdown values to Select
            app.StartingStateDropDown.Value = 'Select';
            app.FinalStateDropDown.Value = 'Select';

            app.ProcessDropDownNew.Value = 'Unknown';

        end

        % Value changed function: IsoBarCheckBox
        function IsoBarCheckBoxValueChanged(app, event)
            value = app.IsoBarCheckBox.Value;

            if value ==1
                app.IsoBarLineValue.Enable = 1;
            else
                app.IsoBarLineValue.Enable = 0;
            end

        end

        % Value changed function: IsoThermCheckBox
        function IsoThermCheckBoxValueChanged(app, event)
            value = app.IsoThermCheckBox.Value;
            if value ==1
                app.IsoThermValue.Enable = 1;
            else
                app.IsoThermValue.Enable = 0;
            end
        end

        % Value changed function: ConstantVolCheckBox
        function ConstantVolCheckBoxValueChanged(app, event)

            value = app.ConstantVolCheckBox.Value;
            if value ==1
                app.ConstantVolValue.Enable = 1;
            else
                app.ConstantVolValue.Enable = 0;
            end


        end

        % Value changed function: IsentropicCheckBox
        function IsentropicCheckBoxValueChanged(app, event)
            value = app.IsentropicCheckBox.Value;
            if value ==1
                app.IsentropicValue.Enable = 1;
            else
                app.IsentropicValue.Enable = 0;
            end
        end

        % Value changed function: UnitPlotXDropDown
        function UnitPlotXDropDownValueChanged(app, event)
            %newXUnits = app.UnitPlotXDropDown.Value;
            plotType = app.PlotDropDown.Value;

            switch plotType
                %update axis labels based on selected units
                case app.plotTypes(1)
                    %P-V
                    xlabel(app.UIAxes,['v ', app.UnitPlotXDropDown.Value])

                case app.plotTypes(2)
                    %T-V
                    xlabel(app.UIAxes,['v ', app.UnitPlotXDropDown.Value])

                case app.plotTypes(3)
                    %T-S
                    xlabel(app.UIAxes,['s ', app.UnitPlotXDropDown.Value])

                otherwise
                    fprintf("Plot type mismatch")

            end


            %then just call plot button pushed and re-render everything
            app.PlotButtonPushed;
        end

        % Value changed function: UnitPlotYDropDown
        function UnitPlotYDropDownValueChanged(app, event)
            %newYUnits = app.UnitPlotYDropDown.Value;
            plotType = app.PlotDropDown.Value;

            switch plotType
                %update axis labels based on selected units
                case app.plotTypes(1)
                    %P-V
                    ylabel(app.UIAxes,['P ', app.UnitPlotYDropDown.Value])

                case app.plotTypes(2)
                    %T-V
                    ylabel(app.UIAxes,['T ', app.UnitPlotYDropDown.Value])

                case app.plotTypes(3)
                    %T-S
                    ylabel(app.UIAxes,['T ', app.UnitPlotYDropDown.Value])

                otherwise
                    fprintf("Plot type mismatch")

            end


            %then just call plot button pushed and re-render everything
            app.PlotButtonPushed;
        end

        % Display data changed function: ProStateUITable
        function ProStateUITableDisplayDataChanged(app, event)

            % OLD
            %             newDisplayData = app.ProStateUITable.DisplayData;
            %             %get the current display data from the table in the states tab
            %             %set the display data in the process tab to match
            %             app.ProStateUITable_2.DisplayData = newDisplayData;
            %
            %
            %             fprintf("THE DATA HAS CHANGED");
            %             %alternatively, this has been moved to the add / update button
            %             %pushed callback
        end

        % Value changed function: PlotDropDown
        function PlotDropDownValueChanged(app, event)
            plotType = app.PlotDropDown.Value;

            %update unit dropdowns based on the plot type
            %plotTypes = {'P - V', 'T - V', 'T - S'};
            switch plotType

                case app.plotTypes(1)
                    %P-V
                    app.UnitPlotXDropDown.Items = app.allUnits.v;
                    app.UnitPlotYDropDown.Items = app.allUnits.P;

                    %update axis labels based on selected units
                    xlabel(app.UIAxes,['v ', app.UnitPlotXDropDown.Value])
                    ylabel(app.UIAxes,['P ', app.UnitPlotYDropDown.Value])

                case app.plotTypes(2)
                    %T-V
                    app.UnitPlotXDropDown.Items = app.allUnits.v;
                    app.UnitPlotYDropDown.Items = app.allUnits.T;

                    %update axis labels based on selected units
                    xlabel(app.UIAxes,['v ', app.UnitPlotXDropDown.Value])
                    ylabel(app.UIAxes,['T ', app.UnitPlotYDropDown.Value])

                case app.plotTypes(3)
                    %T-S
                    app.UnitPlotXDropDown.Items = app.allUnits.s;
                    app.UnitPlotYDropDown.Items = app.allUnits.T;

                    %update axis labels based on selected units
                    xlabel(app.UIAxes,['s ', app.UnitPlotXDropDown.Value])
                    ylabel(app.UIAxes,['T ', app.UnitPlotYDropDown.Value])

                otherwise
                    fprintf("Plot type mismatch")

            end

            %now call the PlotButtonPushed callback to immediately update the
            %graph
            PlotButtonPushed(app);


        end

        % Value changed function: FinalStateDropDown
        function FinalStateDropDownValueChanged(app, event)
            %get the labels of each state in the process
            StartingStateLabel = app.StartingStateDropDown.Value;
            FinalStateLabel = app.FinalStateDropDown.Value;

            %deck maintainence aka dropdown items maintainence
            StartingList = app.ProStateUITable.Data(:,1);
            app.StartingStateDropDown.Items = [{'Select'},StartingList(:)'];

            %app.FinalStateDropDown.Items = app.StartingStateDropDown.Items;

            for i = 2:size(app.ProStateUITable.Data,1)

                if strcmp(app.StartingStateDropDown.Items{i},app.FinalStateDropDown.Value)
                    app.StartingStateDropDown.Items(i)=[];
                    break
                end

            end

            %get the properties of at each state

            %both of these still in cell arrays
            %app.ProStateUITable.Data(1,:) %gets the data in row 1
            %app.ProStateUITable.Data(:,1) %gets the data in column 1
            %app.ProStateUITable.Data{1,1}=='a' %only decelled values like
            %being checked, or strcmp'ed

            %run through the table to find the starting and final indices
            StartingStateIndex = 0;
            FinalStateIndex = 0;
            for i = 1:size(app.ProStateUITable.Data,1)
                %check if the state label matches the starting or final
                %state label
                if strcmp(app.ProStateUITable.Data{i,1},StartingStateLabel)
                    StartingStateIndex = i;
                elseif strcmp(app.ProStateUITable.Data{i,1},FinalStateLabel)
                    FinalStateIndex = i;
                end

                if StartingStateIndex < 1 || FinalStateIndex < 1
                    fprintf("no matching state labels")
                else
                    %get the data
                    %keep it in cells otherwise it all just breaks apart
                    %and that's no good
                    StartingStateData = app.ProStateUITable.Data(StartingStateIndex,:);
                    FinalStateData = app.ProStateUITable.Data(FinalStateIndex,:);
                    %app.ProStateUITable.Data(2,:)
                    %                     StartingStateData
                    %                     FinalStateData


                    Process = ProcessDetect(StartingStateData,FinalStateData,app.processDetectTolerance);

                    %a = app.ProcessDropDownNew.Items;
                    app.ProcessDropDownNew.Value = Process;

                end

            end


            %StartingStateIndex = strcmp(app.ProStateUITable.Data{:,1},StartingStateLabel)
            %StartingStateData = app.ProStateUITable.Data

            %ProcessDetect(app.Table)


        end

        % Value changed function: WorkingFluidDropdown
        function WorkingFluidDropdownValueChanged(app, event)
            value = app.WorkingFluidDropdown.Value;

            %if strcmp(value,"Water") %avoids typing in strings that should
            %match in two different places in the code
            if strcmp(value,app.workingFluids{1})
                [app.Table,app.Critical] = GenTableNew;
                app.plotLimits = app.plotLimitsWater;
                %elseif strcmp(value,"R134a")
            elseif strcmp(value,app.workingFluids{2})
                [app.Table,app.Critical] = GenTableR134a;
                app.plotLimits = app.plotLimitsR134a;
            else
                fprintf("Invalid working fluid")
            end

        end

        % Value changed function: PlotFluidDropDown
        function PlotFluidDropDownValueChanged(app, event)
            %value = app.PlotFluidDropDown.Value;

            %just update the plot
            app.PlotButtonPushed()

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.StatesTab = uitab(app.TabGroup);
            app.StatesTab.Title = 'States';
            app.GridLayout5 = uigridlayout(app.StatesTab);
            app.ProPropertyNo1DropDown = uidropdown(app.GridLayout5);
            app.ProPropertyNo1DropDown.Items = {'Select', 'Pressure', 'Temperature', 'Specific Volume - v', 'Internal Energy - u', 'Enthalpy - h', 'Entropy - s', 'x'};
            app.ProPropertyNo1DropDown.ValueChangedFcn = createCallbackFcn(app, @ProPropertyNo1DropDownValueChanged, true);
            app.ProPropertyNo1DropDown.Value = 'Select';
            app.ProPropertyNo2DropDown = uidropdown(app.GridLayout5);
            app.ProPropertyNo2DropDown.Items = {'Select', 'Pressure', 'Temperature', 'Specific Volume - v', 'Internal Energy - u', 'Enthalpy - h', 'Entropy - s', 'x'};
            app.ProPropertyNo2DropDown.ValueChangedFcn = createCallbackFcn(app, @ProPropertyNo2DropDownValueChanged, true);
            app.ProPropertyNo2DropDown.Value = 'Select';
            app.ProProp1EditField = uieditfield(app.GridLayout5, 'numeric');
            app.ProProp2EditField = uieditfield(app.GridLayout5, 'numeric');
            app.ProStateUITable = uitable(app.GridLayout5);
            app.ProStateUITable.ColumnName = {'State Label'; 'Fluid'; 'Phase'; 'P'; 'T'; 'v'; 'u'; 'h'; 's'; 'x'};
            app.ProStateUITable.RowName = {};
            app.ProStateUITable.CellSelectionCallback = createCallbackFcn(app, @ProStateUITableCellSelection, true);
            app.ProStateUITable.DisplayDataChangedFcn = createCallbackFcn(app, @ProStateUITableDisplayDataChanged, true);
            app.ProUnitProp1DropDown = uidropdown(app.GridLayout5);
            app.ProUnitProp1DropDown.Items = {};
            app.ProUnitProp1DropDown.Value = {};
            app.ProUnitProp2DropDown = uidropdown(app.GridLayout5);
            app.ProUnitProp2DropDown.Items = {};
            app.ProUnitProp2DropDown.Value = {};
            app.TableAStatesListLabel = uilabel(app.GridLayout5);
            app.TableAStatesListLabel.Text = ' Table A - States List';
            app.AddButton = uibutton(app.GridLayout5, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add/Update';
            app.UnitLabel_2 = uilabel(app.GridLayout5);
            app.UnitLabel_2.Text = 'Unit';
            app.ProcessDropDown = uidropdown(app.GridLayout5);
            app.ProcessDropDown.Items = {'Select', 'Isobar', 'Isotherm', 'Constant Volume'};
            app.ProcessDropDown.ValueChangedFcn = createCallbackFcn(app, @ProcessDropDownValueChanged, true);
            app.ProcessDropDown.Enable = 'off';
            app.ProcessDropDown.Value = 'Select';
            app.ProcessCheckBox = uicheckbox(app.GridLayout5);
            app.ProcessCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcessCheckBoxValueChanged, true);
            app.ProcessCheckBox.Enable = 'off';
            app.ProcessCheckBox.Text = ' Process';
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = 'Property No.2';
            app.PropertyNo1Label = uilabel(app.GridLayout5);
            app.PropertyNo1Label.Text = 'Property No.1';
            app.RemoveButton = uibutton(app.GridLayout5, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.StateLabelEditField_2Label = uilabel(app.GridLayout5);
            app.StateLabelEditField_2Label.Text = 'State Label';
            app.StateLablel = uieditfield(app.GridLayout5, 'text');
            app.WorkingFluidDropdown = uidropdown(app.GridLayout5);
            app.WorkingFluidDropdown.Items = {};
            app.WorkingFluidDropdown.ValueChangedFcn = createCallbackFcn(app, @WorkingFluidDropdownValueChanged, true);
            app.WorkingFluidDropdown.Value = {};
            app.WorkingFluidLabel = uilabel(app.GridLayout5);
            app.WorkingFluidLabel.Text = 'Working Fluid';
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.GridLayout2_2 = uigridlayout(app.ProcessTab);
            app.ProUITable = uitable(app.GridLayout2_2);
            app.ProUITable.ColumnName = {'Process Label'; 'Fluid'; 'Start State'; 'Final State'; 'Process Type'};
            app.ProUITable.RowName = {};
            app.ProUITable.CellSelectionCallback = createCallbackFcn(app, @ProUITableCellSelection, true);
            app.ProcessDropDown_2Label = uilabel(app.GridLayout2_2);
            app.ProcessDropDown_2Label.Text = 'Process';
            app.ProcessDropDownNew = uidropdown(app.GridLayout2_2);
            app.ProcessDropDownNew.Items = {'Select', 'Isobar', 'Isotherm', 'Constant Volume', 'Isentropic', 'Unknown'};
            app.ProcessDropDownNew.ValueChangedFcn = createCallbackFcn(app, @ProcessDropDownNewValueChanged, true);
            app.ProcessDropDownNew.Value = 'Select';
            app.ProcessAddButton = uibutton(app.GridLayout2_2, 'push');
            app.ProcessAddButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAddButtonPushed, true);
            app.ProcessAddButton.Text = 'Add/Update';
            app.StartingStateDropDownLabel = uilabel(app.GridLayout2_2);
            app.StartingStateDropDownLabel.Text = 'Starting State';
            app.StartingStateDropDown = uidropdown(app.GridLayout2_2);
            app.StartingStateDropDown.Items = {'Select'};
            app.StartingStateDropDown.ValueChangedFcn = createCallbackFcn(app, @StartingStateDropDownValueChanged, true);
            app.StartingStateDropDown.Value = 'Select';
            app.FinalStateDropDownLabel = uilabel(app.GridLayout2_2);
            app.FinalStateDropDownLabel.Text = 'Final State';
            app.FinalStateDropDown = uidropdown(app.GridLayout2_2);
            app.FinalStateDropDown.Items = {'Select'};
            app.FinalStateDropDown.ValueChangedFcn = createCallbackFcn(app, @FinalStateDropDownValueChanged, true);
            app.FinalStateDropDown.Value = 'Select';
            app.ProRemoveButton = uibutton(app.GridLayout2_2, 'push');
            app.ProRemoveButton.ButtonPushedFcn = createCallbackFcn(app, @ProRemoveButtonPushed, true);
            app.ProRemoveButton.Text = 'Remove';
            app.ProcessLabelEditFieldLabel = uilabel(app.GridLayout2_2);
            app.ProcessLabelEditFieldLabel.Text = 'Process Label';
            app.ProcessLabelEditField = uieditfield(app.GridLayout2_2, 'text');
            app.ProcessListBox = uilistbox(app.GridLayout2_2);
            app.ProcessListBox.Items = {};
            app.ProcessListBox.Multiselect = 'on';
            app.ProcessListBox.Value = {};
            app.ProcessListBoxLabel = uilabel(app.GridLayout2_2);
            app.ProcessListBoxLabel.Text = 'Processes to Display';
            app.AllProcessesCheckBox = uicheckbox(app.GridLayout2_2);
            app.AllProcessesCheckBox.Text = 'All Processes';
            app.AllProcessesCheckBox.Value = true;
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.GridLayout3 = uigridlayout(app.PlotsTab);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotDropDown = uidropdown(app.GridLayout3);
            app.PlotDropDown.Items = {'Select', 'V - P', 'V - T', 'T - S'};
            app.PlotDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotDropDownValueChanged, true);
            app.PlotDropDown.Value = 'Select';
            app.PlotButton = uibutton(app.GridLayout3, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.IsoLinesCheckBox = uicheckbox(app.GridLayout3);
            app.IsoLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsoLinesCheckBoxValueChanged, true);
            app.IsoLinesCheckBox.Text = '  Iso Lines';
            app.IsoLinesCheckBox.Value = true;
            app.IsoBarLineValue = uieditfield(app.GridLayout3, 'text');
            app.IsoBarCheckBox = uicheckbox(app.GridLayout3);
            app.IsoBarCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsoBarCheckBoxValueChanged, true);
            app.IsoBarCheckBox.Text = '  Isobar Line';
            app.IsoThermCheckBox = uicheckbox(app.GridLayout3);
            app.IsoThermCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsoThermCheckBoxValueChanged, true);
            app.IsoThermCheckBox.Text = '  Isotherm Line';
            app.ConstantVolCheckBox = uicheckbox(app.GridLayout3);
            app.ConstantVolCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConstantVolCheckBoxValueChanged, true);
            app.ConstantVolCheckBox.Text = '  Cons. Volume';
            app.IsoThermValue = uieditfield(app.GridLayout3, 'text');
            app.ConstantVolValue = uieditfield(app.GridLayout3, 'text');
            app.IsentropicCheckBox = uicheckbox(app.GridLayout3);
            app.IsentropicCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsentropicCheckBoxValueChanged, true);
            app.IsentropicCheckBox.Text = '  Isentropic Line';
            app.IsentropicValue = uieditfield(app.GridLayout3, 'text');
            app.UnitPlotXDropDown = uidropdown(app.GridLayout3);
            app.UnitPlotXDropDown.Items = {};
            app.UnitPlotXDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitPlotXDropDownValueChanged, true);
            app.UnitPlotXDropDown.Value = {};
            app.UnitPlotYDropDown = uidropdown(app.GridLayout3);
            app.UnitPlotYDropDown.Items = {};
            app.UnitPlotYDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitPlotYDropDownValueChanged, true);
            app.UnitPlotYDropDown.Value = {};
            app.UnitPlotXDropDownLabel = uilabel(app.GridLayout3);
            app.UnitPlotXDropDownLabel.Text = 'X units';
            app.UnitPlotYDropDownLabel = uilabel(app.GridLayout3);
            app.UnitPlotYDropDownLabel.Text = 'Y units';
            app.UnitIsoBarDropDown = uidropdown(app.GridLayout3);
            app.UnitIsoBarDropDown.Items = {};
            app.UnitIsoBarDropDown.Value = {};
            app.UnitIsoThermDropDown = uidropdown(app.GridLayout3);
            app.UnitIsoThermDropDown.Items = {};
            app.UnitIsoThermDropDown.Value = {};
            app.UnitIsoVolDropDown = uidropdown(app.GridLayout3);
            app.UnitIsoVolDropDown.Items = {};
            app.UnitIsoVolDropDown.Value = {};
            app.UnitIsentropicDropDown = uidropdown(app.GridLayout3);
            app.UnitIsentropicDropDown.Items = {};
            app.UnitIsentropicDropDown.Value = {};
            app.PlotFluidLabel = uilabel(app.GridLayout3);
            app.PlotFluidLabel.Text = 'Plot Fluid';
            app.PlotFluidDropDown = uidropdown(app.GridLayout3);
            app.PlotFluidDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotFluidDropDownValueChanged, true);
            app.DPlotTab = uitab(app.TabGroup);
            app.DPlotTab.Title = '3D Plot';
            app.GridLayout4 = uigridlayout(app.DPlotTab);
            app.Plot3D = uiaxes(app.GridLayout4);
            title(app.Plot3D, 'Title')
            xlabel(app.Plot3D, 'X')
            ylabel(app.Plot3D, 'Y')
            app.PropertyCalcoldTab = uitab(app.TabGroup);
            app.PropertyCalcoldTab.Title = 'Property Calc (old)';
            app.GridLayout2 = uigridlayout(app.PropertyCalcoldTab);
            app.PropertyNo1DropDownLabel = uilabel(app.GridLayout2);
            app.PropertyNo1DropDownLabel.Text = 'Property No.1';
            app.PropertyNo1DropDown = uidropdown(app.GridLayout2);
            app.PropertyNo1DropDown.Items = {'Select', 'Pressure', 'Temperature', 'Specific Volume - v', 'Internal Energy - u', 'Enthalpy - h', 'Entropy - s', 'x'};
            app.PropertyNo1DropDown.ValueChangedFcn = createCallbackFcn(app, @PropertyNo1DropDownValueChanged, true);
            app.PropertyNo1DropDown.Value = 'Select';
            app.PropertyNo2DropDownLabel = uilabel(app.GridLayout2);
            app.PropertyNo2DropDownLabel.Text = 'Property No.2';
            app.PropertyNo2DropDown = uidropdown(app.GridLayout2);
            app.PropertyNo2DropDown.Items = {'Select', 'Pressure', 'Temperature', 'Sprecifiv Volume - v', 'Specific Internal Energy - u', 'Specific Enthropy - h', 'x'};
            app.PropertyNo2DropDown.ValueChangedFcn = createCallbackFcn(app, @PropertyNo2DropDownValueChanged, true);
            app.PropertyNo2DropDown.Value = 'Select';
            app.Prop1EditField = uieditfield(app.GridLayout2, 'numeric');
            app.Prop2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Phase'; 'P'; 'T'; 'v'; 'u'; 'h'; 'x'};
            app.UITable.RowName = {};
            app.UnitProp1DropDown = uidropdown(app.GridLayout2);
            app.UnitProp1DropDown.Items = {};
            app.UnitProp1DropDown.Value = {};
            app.UnitProp2DropDown = uidropdown(app.GridLayout2);
            app.UnitProp2DropDown.Items = {};
            app.UnitProp2DropDown.Value = {};
            app.StatesPropertiesLabel = uilabel(app.GridLayout2);
            app.StatesPropertiesLabel.Text = ' States Properties';
            app.CalculateButton = uibutton(app.GridLayout2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UnitLabel = uilabel(app.GridLayout2);
            app.UnitLabel.Text = 'Unit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = realApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end